classdef musselHeater_04jun2019 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        TankATab                 matlab.ui.container.Tab
        ImportDataButton         matlab.ui.control.Button
        ExportDataButton         matlab.ui.control.Button
        Temp1_graph              matlab.ui.control.UIAxes
        Height1_graph            matlab.ui.control.UIAxes
        RunProgram1CheckBox      matlab.ui.control.CheckBox
        ConnecttoArduino1Button  matlab.ui.control.StateButton
        M1SetTextAreaLabel       matlab.ui.control.Label
        M1SetTextArea            matlab.ui.control.TextArea
        M1ActualTextAreaLabel    matlab.ui.control.Label
        M1ActualTextArea         matlab.ui.control.TextArea
        M2SetTextAreaLabel       matlab.ui.control.Label
        M2SetTextArea            matlab.ui.control.TextArea
        M2ActualTextAreaLabel    matlab.ui.control.Label
        M2ActualTextArea         matlab.ui.control.TextArea
        M3SetTextAreaLabel       matlab.ui.control.Label
        M3SetTextArea            matlab.ui.control.TextArea
        M3ActualTextAreaLabel    matlab.ui.control.Label
        M3ActualTextArea         matlab.ui.control.TextArea
        WLSetTextAreaLabel       matlab.ui.control.Label
        WLSetTextArea            matlab.ui.control.TextArea
        WLActualTextAreaLabel    matlab.ui.control.Label
        WLActualTextArea         matlab.ui.control.TextArea
        M4SetTextArea_2Label     matlab.ui.control.Label
        M4SetTextArea            matlab.ui.control.TextArea
        M4ActualTextArea_2Label  matlab.ui.control.Label
        M4ActualTextArea         matlab.ui.control.TextArea
        M5SetTextArea_2Label     matlab.ui.control.Label
        M5SetTextArea            matlab.ui.control.TextArea
        M5ActualTextArea_2Label  matlab.ui.control.Label
        M5ActualTextArea         matlab.ui.control.TextArea
        M6SetTextArea_2Label     matlab.ui.control.Label
        M6SetTextArea            matlab.ui.control.TextArea
        M6ActualTextArea_2Label  matlab.ui.control.Label
        M6ActualTextArea         matlab.ui.control.TextArea
        M7SetTextArea_2Label     matlab.ui.control.Label
        M7SetTextArea            matlab.ui.control.TextArea
        M7ActualTextArea_2Label  matlab.ui.control.Label
        M7ActualTextArea         matlab.ui.control.TextArea
        M8SetTextArea_2Label     matlab.ui.control.Label
        M8SetTextArea            matlab.ui.control.TextArea
        M8ActualTextArea_2Label  matlab.ui.control.Label
        M8ActualTextArea         matlab.ui.control.TextArea
        M9SetTextArea_2Label     matlab.ui.control.Label
        M9SetTextArea            matlab.ui.control.TextArea
        M9ActualTextArea_2Label  matlab.ui.control.Label
        M9ActualTextArea         matlab.ui.control.TextArea
        ComPortEditFieldLabel    matlab.ui.control.Label
        ComPortEditField         matlab.ui.control.EditField
        TankBTab                 matlab.ui.container.Tab
        Temp2_graph              matlab.ui.control.UIAxes
        Height2_graph            matlab.ui.control.UIAxes
        ImportDataButton_2       matlab.ui.control.Button
        ExportDataButton_2       matlab.ui.control.Button
        M1SetTextArea_2Label     matlab.ui.control.Label
        M1SetTextArea_2          matlab.ui.control.TextArea
        M1ActualTextArea_2Label  matlab.ui.control.Label
        M1ActualTextArea_2       matlab.ui.control.TextArea
        M2SetTextArea_2Label     matlab.ui.control.Label
        M2SetTextArea_2          matlab.ui.control.TextArea
        M2ActualTextArea_2Label  matlab.ui.control.Label
        M2ActualTextArea_2       matlab.ui.control.TextArea
        M3SetTextArea_2Label     matlab.ui.control.Label
        M3SetTextArea_2          matlab.ui.control.TextArea
        M3ActualTextArea_2Label  matlab.ui.control.Label
        M3ActualTextArea_2       matlab.ui.control.TextArea
        WLSetTextArea_2Label     matlab.ui.control.Label
        WLSetTextArea_2          matlab.ui.control.TextArea
        WLActualTextArea_2Label  matlab.ui.control.Label
        WLActualTextArea_2       matlab.ui.control.TextArea
        RunProgram2CheckBox      matlab.ui.control.CheckBox
        ConnecttoArduino2Button  matlab.ui.control.StateButton
        M4ActualTextAreaLabel    matlab.ui.control.Label
        M4ActualTextArea_2       matlab.ui.control.TextArea
        M4SetTextAreaLabel       matlab.ui.control.Label
        M4SetTextArea_2          matlab.ui.control.TextArea
        M5ActualTextAreaLabel    matlab.ui.control.Label
        M5ActualTextArea_2       matlab.ui.control.TextArea
        M5SetTextAreaLabel       matlab.ui.control.Label
        M5SetTextArea_2          matlab.ui.control.TextArea
        M6ActualTextAreaLabel    matlab.ui.control.Label
        M6ActualTextArea_2       matlab.ui.control.TextArea
        M6SetTextAreaLabel       matlab.ui.control.Label
        M6SetTextArea_2          matlab.ui.control.TextArea
        M7ActualTextAreaLabel    matlab.ui.control.Label
        M7ActualTextArea_2       matlab.ui.control.TextArea
        M7SetTextAreaLabel       matlab.ui.control.Label
        M7SetTextArea_2          matlab.ui.control.TextArea
        M8ActualTextAreaLabel    matlab.ui.control.Label
        M8ActualTextArea_2       matlab.ui.control.TextArea
        M8SetTextAreaLabel       matlab.ui.control.Label
        M8SetTextArea_2          matlab.ui.control.TextArea
        M9ActualTextAreaLabel    matlab.ui.control.Label
        M9ActualTextArea_2       matlab.ui.control.TextArea
        M9SetTextAreaLabel       matlab.ui.control.Label
        M9SetTextArea_2          matlab.ui.control.TextArea
        ComPortEditField_2Label  matlab.ui.control.Label
        ComPortEditField_2       matlab.ui.control.EditField
    end


    properties (Access = private)
        arduino1;
        arduino2;
        comPort1;
        comPort2;
        % set length to 200 for now, can't figure out how to make it dependent on imported file
        dataDesired1 = nan(10,200); % data array for storing imported data to tank 1, only y values
        dataDesired2 = nan(10,200);% data array for storing imported data to tank 2, only y values
        timeDesired1 = nan(1,200); % data array for storing imported time data
        timeDesired2 = nan(1,200); % data array for storing imported time data
        dataRecorded1 = nan(10,200);% data array for storing Arduino read data to tank 1, only y values (no time)
        dataRecorded2 = nan(10,200); % data array for storing Arduino read data to tank 2, only y values (no time)
        timeRecorded1 = nan(1,200); % data array for storing computer clock time of recorded readings
        timeRecorded2 = nan(1,200); % data array for storing computer clock time of recorded readings
    end

    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.csv');
            % Name of the file to be imported
            defaultFileName = 'musselRun_A.csv';
            % If a file is sucessfully selected and importered, do the following:
            if (((isa(FileName, 'char'))) && ((isa(PathName, 'char'))) && (strcmp(FileName, defaultFileName)))
                % Actually load the file
                fileRead = fullfile(PathName, FileName);
                imported1 = csvread(fileRead,1,0);
                
                % Saves the time data into the timeDesired1 vector
                app.timeDesired1(1,:) = imported1(:,1);
                % Saves the water level data into the dataDesired1 second column
                app.dataDesired1(1,:) = imported1(:,2);
                % Saves the temperature data for MUSSEL 1 into the dataDesired1 third column
                app.dataDesired1(2,:) = imported1(:,3);
                % Saves the temperature data for MUSSEL 2 into the 'desired' data block
                app.dataDesired1(3,:) = imported1(:,4);
                % Saves the temperature data for MUSSEL 3 into the 'desired' data block
                app.dataDesired1(4,:) = imported1(:,5);
                % Saves the temperature data for MUSSEL 4 into the 'desired' data block
                app.dataDesired1(5,:) = imported1(:,6);
                % Saves the temperature data for MUSSEL 5 into the 'desired' data block
                app.dataDesired1(6,:) = imported1(:,7);
                % Saves the temperature data for MUSSEL 6 into the 'desired' data block
                app.dataDesired1(7,:) = imported1(:,8);
                % Saves the temperature data for MUSSEL 7 into the 'desired' data block
                app.dataDesired1(8,:) = imported1(:,9);
                % Saves the temperature data for MUSSEL 8 into the 'desired' data block
                app.dataDesired1(9,:) = imported1(:,10);
                % Saves the temperature data for MUSSEL 9 into the 'desired' data block
                app.dataDesired1(10,:) = imported1(:,11);

            else
                % Otherwise, create error dialog that indicates incorrect import and
                % returns proper instructions
                errordlg(['Incorrect import. Please import a file named ' defaultFileName]);
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            %put all data together (time/readings for desired and recorded) 
            fullData = [app.timeRecorded1; app.dataRecorded1; app.timeDesired1; app.dataDesired1];
            % transpose
            fullDataTrans = fullData.';
            csvwrite(filename,fullDataTrans); %exports recorded data
        end

        % Value changed function: ConnecttoArduino1Button
        function ConnecttoArduino1ButtonValueChanged(app, event)
            %value is whether the button is clicked (1) or not (0)
            value = app.ConnecttoArduino1Button.Value;
            if value == 1
                % open a serial connection if the button is clicked
                serialFlag = 0;
                while (serialFlag ~= 1)
                  [arduino_1, serialFlag] = setupSerial(app.comPort1);
                  app.arduino1 = arduino_1;
                end
            end
            if value == 0
                % close the serial connection if the button is unclicked
                fclose(app.arduino1);
                clear app.arduino1;
            end
        end

        % Value changed function: RunProgram1CheckBox
        function RunProgram1CheckBoxValueChanged(app, event)
            value = app.RunProgram1CheckBox.Value;
            % initialize the plots
            % initialize the table
            %rowNames = {'Water Level', 'Mussel 1', 'Mussel 2', 'Mussel 3'};
            % app.UITable('RowName', rowNames ,'Data', tableInitial, 'ColumnEditable', false);
            if value == 1
                % run the communication function for Arduino, tank A
                % continuously loop over time, use tic/toc (seconds)
                % only do this when you reach a mark in the time column of desiredData file
                % continuously update figures and table
                % arduinoReceived = arduinoComm(tank, desWL, desM1, desM2, desM3)
                    %plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(2,:),'r',app.timeRecorded1(1,:),app.dataRecorded1(2,:),'--r', app.timeDesired1(1,:), app.dataDesired1(3,:),'g',app.timeRecorded1(1,:),app.dataRecorded1(3,:),'--g', app.timeDesired1(1,:), app.dataDesired1(4,:),'b', app.timeRecorded1(1,:),app.dataRecorded1(4,:),'--b');
                    %plot(app.Height1_graph,app.timeDesired1(1,:),app.dataDesired1(1,:),'r',app.timeRecorded1(1,:),app.dataRecorded1(1,:),'--r');
                
                    
                rateObj = robotics.Rate(0.0333333); % every 30s %(0.016666667); % every minute
                time = app.timeDesired1; %WD NOTE: currently in seconds, update to time of day
                for j = 1:length(time)
                    waitfor(rateObj);
                    % call arduinoComm function to push string to Arduino and receive string back
                    % arduino, tank, M1, M2, M3, WL
                    arduinoReceivedA = arduinoComm(app.arduino1, 'A', app.dataDesired1(1,j), app.dataDesired1(2,j), app.dataDesired1(3,j), app.dataDesired1(4,j), app.dataDesired1(5,j), app.dataDesired1(6,j), app.dataDesired1(7,j), app.dataDesired1(8,j), app.dataDesired1(9,j), app.dataDesired1(10,j));
                    tA = strsplit(arduinoReceivedA); % same format as the Arduino code, even though \t is in Arduino it doesn't seem to need it
                    
                    %need to convert cell to double to be able to print to GUI
                    %tank doesn't get put into this because it's a character, becomes NaN
                    tA_new = str2double(tA);
                    tA_new(tA_new<0) = NaN; %WD 04jun2019 replace negative/missing values with NaN
                    if tA{1}=='A'  %tA_new(1) == 'A' %WD 04jun2019
                        % populate dataRecorded1
                        app.timeRecorded1(1,j) = rateObj.TotalElapsedTime;
                        % M1-9
                        app.dataRecorded1(2,j) = tA_new(2);
                        app.dataRecorded1(3,j) = tA_new(3);
                        app.dataRecorded1(4,j) = tA_new(4);
                        app.dataRecorded1(5,j) = tA_new(5);
                        app.dataRecorded1(6,j) = tA_new(6);
                        app.dataRecorded1(7,j) = tA_new(7);
                        app.dataRecorded1(8,j) = tA_new(8);
                        app.dataRecorded1(9,j) = tA_new(9);
                        app.dataRecorded1(10,j) = tA_new(10);
                        % WL
                        app.dataRecorded1(1,j) = tA_new(11);
                        % print to the plots and table
                        % also print set points (desired) in a different line style
                        %WD 04jun2019 made readable in one view and changed color codes last 4 plots
                        %ax = Temp1_graph;
                        plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(2,:),'r')  %was '--r', then 'r'
                        hold(app.Temp1_graph);%,...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(2,:),'r.','MarkerSize',5)%,...
                            plot(app.Temp1_graph,app.timeDesired1(1,:), app.dataDesired1(3,:),'g')%,...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(3,:),'g.','MarkerSize',5)%,...
                            plot(app.Temp1_graph,app.timeDesired1(1,:), app.dataDesired1(4,:),'b')%,...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(4,:),'b.','MarkerSize',5)%,...
                            plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(5,:),'c')%,...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(5,:),'c.','MarkerSize',5)%,...
                            plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(6,:),'m')%,...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(6,:),'m.','MarkerSize',5)%,...
                            plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(7,:),'y')%,...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(7,:),'y.','MarkerSize',5)%,...
                            plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(8,:),'k')%,...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(8,:),'k.','MarkerSize',5)%,...
                            plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(9,:),'--r')%,'LineWidth',3)%,... %[0, 0.75, 0.75],...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(9,:),'r.','MarkerSize',5)%,'LineWidth',3)%,... %[0, 0.75, 0.75],...
                            plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(10,:),'--k')%,'LineWidth',3)%,... %[0.75, 0.75, 0],...
                            plot(app.Temp1_graph,app.timeRecorded1(1,:),app.dataRecorded1(10,:),'ko','MarkerSize',5)%,'LineWidth',3) %[0.75, 0.75, 0]);
                       hold(app.Temp1_graph); 
                       plot(app.Height1_graph,app.timeDesired1(1,:),app.dataDesired1(1,:),'r',... %was '--r'
                            app.timeRecorded1(1,:),app.dataRecorded1(1,:),'r.','MarkerSize',5);  %WD 04jun2019 was...
                            %applicationCompiler.timeRecorded(1,:)
                            
                        % fill the set/actual feedback text areas for all mussels and water level
                        app.M1SetTextArea.Value = num2str(app.dataDesired1(2,j),'%0.2f');
                        app.M1ActualTextArea.Value = num2str(app.dataRecorded1(2,j),'%0.2f');
                        app.M2SetTextArea.Value = num2str(app.dataDesired1(3,j),'%0.2f');
                        app.M2ActualTextArea.Value = num2str(app.dataRecorded1(3,j),'%0.2f');
                        app.M3SetTextArea.Value = num2str(app.dataDesired1(4,j),'%0.2f');
                        app.M3ActualTextArea.Value = num2str(app.dataRecorded1(4,j),'%0.2f');
                        app.M4SetTextArea.Value = num2str(app.dataDesired1(5,j),'%0.2f');
                        app.M4ActualTextArea.Value = num2str(app.dataRecorded1(5,j),'%0.2f');
                        app.M5SetTextArea.Value = num2str(app.dataDesired1(6,j),'%0.2f');
                        app.M5ActualTextArea.Value = num2str(app.dataRecorded1(6,j),'%0.2f');
                        app.M6SetTextArea.Value = num2str(app.dataDesired1(7,j),'%0.2f');
                        app.M6ActualTextArea.Value = num2str(app.dataRecorded1(7,j),'%0.2f');
                        app.M7SetTextArea.Value = num2str(app.dataDesired1(8,j),'%0.2f');
                        app.M7ActualTextArea.Value = num2str(app.dataRecorded1(8,j),'%0.2f');
                        app.M8SetTextArea.Value = num2str(app.dataDesired1(9,j),'%0.2f');
                        app.M8ActualTextArea.Value = num2str(app.dataRecorded1(9,j),'%0.2f');
                        app.M9SetTextArea.Value = num2str(app.dataDesired1(10,j),'%0.2f');
                        app.M9ActualTextArea.Value = num2str(app.dataRecorded1(10,j),'%0.2f');
                        app.WLSetTextArea.Value = num2str(app.dataDesired1(1,j),'%0.2f');
                        app.WLActualTextArea.Value = num2str(app.dataRecorded1(1,j),'%0.2f');
                    end
                end
            end
        end

        % Button pushed function: ImportDataButton_2
        function ImportDataButton_2Pushed2(app, event)
            [FileName2,PathName2] = uigetfile('*.csv');
            % Name of the file to be imported
            defaultFileName2 = 'musselRun_B.csv';
            % If a file is sucessfully selected and importered, do the following:
            if (((isa(FileName2, 'char'))) && ((isa(PathName2, 'char'))) && (strcmp(FileName2, defaultFileName2)))
                % Actually load the file
                fileRead2 = fullfile(PathName2, FileName2);
                imported2 = readtable(fileRead2,1,0);
                
                % Saves the time data into the dataDesired1 first column
                app.timeDesired2(1,:) = imported2(:,1);
                % Saves the water level data into the dataDesired1 second column
                app.dataDesired2(1,:) = imported2(:,2);
                % Saves the temperature data for MUSSEL 1 into the dataDesired1 third column
                app.dataDesired2(2,:) = imported2(:,3);
                % Saves the temperature data for MUSSEL 2 into the 'desired' data block
                app.dataDesired2(3,:) = imported2(:,4);
                % Saves the temperature data for MUSSEL 3 into the 'desired' data block
                app.dataDesired2(4,:) = imported2(:,5);
                % Saves the temperature data for MUSSEL 4 into the 'desired' data block
                app.dataDesired2(5,:) = imported2(:,6);
                % Saves the temperature data for MUSSEL 5 into the 'desired' data block
                app.dataDesired2(6,:) = imported2(:,7);
                % Saves the temperature data for MUSSEL 6 into the 'desired' data block
                app.dataDesired2(7,:) = imported2(:,8);
                % Saves the temperature data for MUSSEL 7 into the 'desired' data block
                app.dataDesired2(8,:) = imported2(:,9);
                % Saves the temperature data for MUSSEL 8 into the 'desired' data block
                app.dataDesired2(9,:) = imported2(:,10);
                % Saves the temperature data for MUSSEL 9 into the 'desired' data block
                app.dataDesired2(10,:) = imported2(:,11);
                
            else
                % Otherwise, create error dialog that indicates incorrect import and
                % returns proper instructions
                errordlg(['Incorrect import. Please import a file named ' defaultFileName2]);
            end  
        end

        % Button pushed function: ExportDataButton_2
        function ExportDataButton_2Pushed2(app, event)
            [file2,path2] = uiputfile('*.csv');
            filename2 = fullfile(path2,file2);
            %put all data together (time/readings for desired and recorded) 
            fullData2 = [app.timeRecorded2; app.dataRecorded2; app.timeDesired2; app.dataDesired2];
            % transpose
            fullDataTrans2 = fullData2.';
            csvwrite(filename2,fullDataTrans2); %exports recorded data
        end

        % Value changed function: ConnecttoArduino2Button
        function ConnecttoArduino2ButtonValueChanged(app, event)
            %value is whether the button is clicked (1) or not (0)
            value = app.ConnecttoArduino1Button.Value;
            if value == 1
                % open a serial connection if the button is clicked
                serialFlag = 0;
                while (serialFlag ~= 1)
                  [arduino_2, serialFlag] = setupSerial(app.comPort2);
                  app.arduino2 = arduino_2;
                end
            end
            if value == 0
                % close the serial connection if the button is unclicked
                fclose(app.arduino2);
                clear app.arduino2;
            end
        end

        % Value changed function: RunProgram2CheckBox
        function RunProgram2CheckBoxValueChanged(app, event)
            value = app.RunProgram2CheckBox.Value;
            % initialize the plots
            % initialize the table
            %rowNames = {'Water Level', 'Mussel 1', 'Mussel 2', 'Mussel 3'};
            % app.UITable('RowName', rowNames ,'Data', tableInitial, 'ColumnEditable', false);
            if value == 1
                % run the communication function for Arduino, tank B
                % continuously loop over time, use tic/toc (seconds)
                % only do this when you reach a mark in the time column of desiredData file
                % continuously update figures and table
                % arduinoReceived = arduinoComm(tank, desWL, desM1, desM2, desM3)
                    %plot(app.Temp1_graph,app.timeDesired1(1,:),app.dataDesired1(2,:),'r',app.timeRecorded1(1,:),app.dataRecorded1(2,:),'--r', app.timeDesired1(1,:), app.dataDesired1(3,:),'g',app.timeRecorded1(1,:),app.dataRecorded1(3,:),'--g', app.timeDesired1(1,:), app.dataDesired1(4,:),'b', app.timeRecorded1(1,:),app.dataRecorded1(4,:),'--b');
                    %plot(app.Height1_graph,app.timeDesired1(1,:),app.dataDesired1(1,:),'r',app.timeRecorded1(1,:),app.dataRecorded1(1,:),'--r');
                rateObj = robotics.Rate(0.016); % every minute
                time = app.timeDesired2;
                for j = 1:length(time)
                    waitfor(rateObj);
                    % call arduinoComm function to push string to Arduino and receive string back
                    % arduino, tank, M1, M2, M3, WL
                    arduinoReceivedB = arduinoComm(app.arduino2, 'B', app.dataDesired2(1,j), app.dataDesired2(2,j), app.dataDesired2(3,j), app.dataDesired2(4,j), app.dataDesired2(5,j), app.dataDesired2(6,j), app.dataDesired2(7,j), app.dataDesired2(8,j), app.dataDesired2(9,j), app.dataDesired2(10,j));
                    tB = strsplit(arduinoReceivedB); % same format as the Arduino code, even though \t is in Arduino it doesn't seem to need it
                    
                    %need to convert cell to double to be able to print to GUI
                    %tank doesn't get put into this because it's a character, becomes NaN
                    tB_new = str2double(tB);
                    % populate dataRecorded1
                    app.timeRecorded2(1,j) = rateObj.TotalElapsedTime;
                    %M1-3
                    app.dataRecorded2(2,j) = tB_new(2);
                    app.dataRecorded2(3,j) = tB_new(3);
                    app.dataRecorded2(4,j) = tB_new(4);
                    %WL
                    app.dataRecorded2(1,j) = tB_new(5);
                    % print to the plots and table
                    % also print set points (desired) in a different line style
                    plot(app.Temp2_graph,app.timeDesired2(1,:),app.dataDesired2(2,:),'--r',app.timeRecorded2(1,:),app.dataRecorded2(2,:),'r', app.timeDesired2(1,:), app.dataDesired2(3,:),'--g',app.timeRecorded2(1,:),app.dataRecorded2(3,:),'g', app.timeDesired2(1,:), app.dataDesired2(4,:),'--b',app.timeRecorded2(1,:),app.dataRecorded2(4,:),'b',app.timeDesired2(1,:),app.dataDesired2(5,:),'--c',app.timeRecorded2(1,:),app.dataRecorded2(5,:),'c',app.timeDesired2(1,:),app.dataDesired2(6,:),'--m',app.timeRecorded2(1,:),app.dataRecorded2(6,:),'m',app.timeDesired2(1,:),app.dataDesired2(7,:),'--y',app.timeRecorded2(1,:),app.dataRecorded2(7,:),'y',app.timeDesired2(1,:),app.dataDesired2(8,:),'--k',app.timeRecorded2(1,:),app.dataRecorded2(8,:),'k',app.timeDesired2(1,:),app.dataDesired2(9,:),'--',[0, 0.75, 0.75],app.timeRecorded2(1,:),app.dataRecorded2(9,:),[0, 0.75, 0.75],app.timeDesired2(1,:),app.dataDesired2(10,:),'--',[0.75, 0.75, 0],app.timeRecorded2(1,:),app.dataRecorded2(10,:),[0.75, 0.75, 0]);
                    plot(app.Height2_graph,app.timeDesired2(1,:),app.dataDesired2(1,:),'--r',app.timeRecorded2(1,:),app.dataRecorded2(1,:),'r');
                    % fill the set/actual feedback text areas for all mussels and water level
                    app.M1SetTextArea_2.Value = num2str(app.dataDesired2(2,j),'%0.2f');
                    app.M1ActualTextArea_2.Value = num2str(app.dataRecorded2(2,j),'%0.2f');
                    app.M2SetTextArea_2.Value = num2str(app.dataDesired2(3,j),'%0.2f');
                    app.M2ActualTextArea_2.Value = num2str(app.dataRecorded2(3,j),'%0.2f');
                    app.M3SetTextArea_2.Value = num2str(app.dataDesired2(4,j),'%0.2f');
                    app.M3ActualTextArea_2.Value = num2str(app.dataRecorded2(4,j),'%0.2f');
                    app.M4SetTextArea_2.Value = num2str(app.dataDesired2(5,j),'%0.2f');
                    app.M4ActualTextArea_2.Value = num2str(app.dataRecorded2(5,j),'%0.2f');
                    app.M5SetTextArea_2.Value = num2str(app.dataDesired2(6,j),'%0.2f');
                    app.M5ActualTextArea_2.Value = num2str(app.dataRecorded2(6,j),'%0.2f');
                    app.M6SetTextArea_2.Value = num2str(app.dataDesired2(7,j),'%0.2f');
                    app.M6ActualTextArea_2.Value = num2str(app.dataRecorded2(7,j),'%0.2f');
                    app.M7SetTextArea_2.Value = num2str(app.dataDesired2(8,j),'%0.2f');
                    app.M7ActualTextArea_2.Value = num2str(app.dataRecorded2(8,j),'%0.2f');
                    app.M8SetTextArea_2.Value = num2str(app.dataDesired2(9,j),'%0.2f');
                    app.M8ActualTextArea_2.Value = num2str(app.dataRecorded2(9,j),'%0.2f');
                    app.M9SetTextArea_2.Value = num2str(app.dataDesired2(10,j),'%0.2f');
                    app.M9ActualTextArea_2.Value = num2str(app.dataRecorded2(10,j),'%0.2f');
                    app.WLSetTextArea_2.Value = num2str(app.dataDesired2(1,j),'%0.2f');
                    app.WLActualTextArea_2.Value = num2str(app.dataRecorded2(1,j),'%0.2f');
                end
            end            
        end

        % Value changed function: ComPortEditField
        function ComPortEditFieldValueChanged(app, event)
            value = app.ComPortEditField.Value;
            % Saves the comPort that the arduino is connected to on this computer.
            app.comPort1 = string(value);
        end

        % Value changed function: ComPortEditField_2
        function ComPortEditField_2ValueChanged(app, event)
            value = app.ComPortEditField_2.Value;
            % Saves the comPort that the arduino is connected to on this computer.
            app.comPort2 = string(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TankATab = uitab(app.TabGroup);
            app.TankATab.Title = 'Tank A';
            app.ImportDataButton = uibutton(app.TankATab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ExportDataButton = uibutton(app.TankATab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Temp1_graph = uiaxes(app.TankATab);
            title(app.Temp1_graph, 'Tank A Temperature')
            xlabel(app.Temp1_graph, 'Time (seconds)')
            ylabel(app.Temp1_graph, 'Temperature (ºC)')
            app.Temp1_graph.XLim = [0 12000];
            app.Temp1_graph.YLim = [5 35];
            app.Height1_graph = uiaxes(app.TankATab);
            title(app.Height1_graph, 'Tank A Tidal Height')
            xlabel(app.Height1_graph, 'Time (seconds)')
            ylabel(app.Height1_graph, 'Height')
            app.Height1_graph.XLim = [0 12000];
            app.Height1_graph.YLim = [0 10];
            app.RunProgram1CheckBox = uicheckbox(app.TankATab);
            app.RunProgram1CheckBox.ValueChangedFcn = createCallbackFcn(app, @RunProgram1CheckBoxValueChanged, true);
            app.RunProgram1CheckBox.Text = 'Run Program (Tank A)';
            app.ConnecttoArduino1Button = uibutton(app.TankATab, 'state');
            app.ConnecttoArduino1Button.ValueChangedFcn = createCallbackFcn(app, @ConnecttoArduino1ButtonValueChanged, true);
            app.ConnecttoArduino1Button.Text = 'Connect to Arduino';
            app.M1SetTextAreaLabel = uilabel(app.TankATab);
            app.M1SetTextAreaLabel.Text = 'M1 Set';
            app.M1SetTextArea = uitextarea(app.TankATab);
            app.M1SetTextArea.Editable = 'off';
            app.M1ActualTextAreaLabel = uilabel(app.TankATab);
            app.M1ActualTextAreaLabel.Text = 'M1 Actual';
            app.M1ActualTextArea = uitextarea(app.TankATab);
            app.M1ActualTextArea.Editable = 'off';
            app.M2SetTextAreaLabel = uilabel(app.TankATab);
            app.M2SetTextAreaLabel.Text = 'M2 Set';
            app.M2SetTextArea = uitextarea(app.TankATab);
            app.M2SetTextArea.Editable = 'off';
            app.M2ActualTextAreaLabel = uilabel(app.TankATab);
            app.M2ActualTextAreaLabel.Text = 'M2 Actual';
            app.M2ActualTextArea = uitextarea(app.TankATab);
            app.M2ActualTextArea.Editable = 'off';
            app.M3SetTextAreaLabel = uilabel(app.TankATab);
            app.M3SetTextAreaLabel.Text = 'M3 Set';
            app.M3SetTextArea = uitextarea(app.TankATab);
            app.M3SetTextArea.Editable = 'off';
            app.M3ActualTextAreaLabel = uilabel(app.TankATab);
            app.M3ActualTextAreaLabel.Text = 'M3 Actual';
            app.M3ActualTextArea = uitextarea(app.TankATab);
            app.M3ActualTextArea.Editable = 'off';
            app.WLSetTextAreaLabel = uilabel(app.TankATab);
            app.WLSetTextAreaLabel.Text = 'WL Set';
            app.WLSetTextArea = uitextarea(app.TankATab);
            app.WLSetTextArea.Editable = 'off';
            app.WLActualTextAreaLabel = uilabel(app.TankATab);
            app.WLActualTextAreaLabel.Text = 'WL Actual';
            app.WLActualTextArea = uitextarea(app.TankATab);
            app.WLActualTextArea.Editable = 'off';
            app.M4SetTextArea_2Label = uilabel(app.TankATab);
            app.M4SetTextArea_2Label.Text = 'M4 Set';
            app.M4SetTextArea = uitextarea(app.TankATab);
            app.M4SetTextArea.Editable = 'off';
            app.M4ActualTextArea_2Label = uilabel(app.TankATab);
            app.M4ActualTextArea_2Label.Text = 'M4 Actual';
            app.M4ActualTextArea = uitextarea(app.TankATab);
            app.M4ActualTextArea.Editable = 'off';
            app.M5SetTextArea_2Label = uilabel(app.TankATab);
            app.M5SetTextArea_2Label.Text = 'M5 Set';
            app.M5SetTextArea = uitextarea(app.TankATab);
            app.M5SetTextArea.Editable = 'off';
            app.M5ActualTextArea_2Label = uilabel(app.TankATab);
            app.M5ActualTextArea_2Label.Text = 'M5 Actual';
            app.M5ActualTextArea = uitextarea(app.TankATab);
            app.M5ActualTextArea.Editable = 'off';
            app.M6SetTextArea_2Label = uilabel(app.TankATab);
            app.M6SetTextArea_2Label.Text = 'M6 Set';
            app.M6SetTextArea = uitextarea(app.TankATab);
            app.M6SetTextArea.Editable = 'off';
            app.M6ActualTextArea_2Label = uilabel(app.TankATab);
            app.M6ActualTextArea_2Label.Text = 'M6 Actual';
            app.M6ActualTextArea = uitextarea(app.TankATab);
            app.M6ActualTextArea.Editable = 'off';
            app.M7SetTextArea_2Label = uilabel(app.TankATab);
            app.M7SetTextArea_2Label.Text = 'M7 Set';
            app.M7SetTextArea = uitextarea(app.TankATab);
            app.M7SetTextArea.Editable = 'off';
            app.M7ActualTextArea_2Label = uilabel(app.TankATab);
            app.M7ActualTextArea_2Label.Text = 'M7 Actual';
            app.M7ActualTextArea = uitextarea(app.TankATab);
            app.M7ActualTextArea.Editable = 'off';
            app.M8SetTextArea_2Label = uilabel(app.TankATab);
            app.M8SetTextArea_2Label.Text = 'M8 Set';
            app.M8SetTextArea = uitextarea(app.TankATab);
            app.M8SetTextArea.Editable = 'off';
            app.M8ActualTextArea_2Label = uilabel(app.TankATab);
            app.M8ActualTextArea_2Label.Text = 'M8 Actual';
            app.M8ActualTextArea = uitextarea(app.TankATab);
            app.M8ActualTextArea.Editable = 'off';
            app.M9SetTextArea_2Label = uilabel(app.TankATab);
            app.M9SetTextArea_2Label.Text = 'M9 Set';
            app.M9SetTextArea = uitextarea(app.TankATab);
            app.M9SetTextArea.Editable = 'off';
            app.M9ActualTextArea_2Label = uilabel(app.TankATab);
            app.M9ActualTextArea_2Label.Text = 'M9 Actual';
            app.M9ActualTextArea = uitextarea(app.TankATab);
            app.M9ActualTextArea.Editable = 'off';
            app.ComPortEditFieldLabel = uilabel(app.TankATab);
            app.ComPortEditFieldLabel.Text = 'Com Port:';
            app.ComPortEditField = uieditfield(app.TankATab, 'text');
            app.ComPortEditField.ValueChangedFcn = createCallbackFcn(app, @ComPortEditFieldValueChanged, true);
            app.TankBTab = uitab(app.TabGroup);
            app.TankBTab.Title = 'Tank B';
            app.Temp2_graph = uiaxes(app.TankBTab);
            title(app.Temp2_graph, 'Tank B Temperature')
            xlabel(app.Temp2_graph, 'Time (seconds)')
            ylabel(app.Temp2_graph, 'Temperature (ºC)')
            app.Height2_graph = uiaxes(app.TankBTab);
            title(app.Height2_graph, 'Tank B Tidal Height')
            xlabel(app.Height2_graph, 'Time (seconds)')
            ylabel(app.Height2_graph, 'Height')
            app.ImportDataButton_2 = uibutton(app.TankBTab, 'push');
            app.ImportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButton_2Pushed2, true);
            app.ImportDataButton_2.Text = 'Import Data';
            app.ExportDataButton_2 = uibutton(app.TankBTab, 'push');
            app.ExportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_2Pushed2, true);
            app.ExportDataButton_2.Text = 'Export Data';
            app.M1SetTextArea_2Label = uilabel(app.TankBTab);
            app.M1SetTextArea_2Label.Text = 'M1 Set';
            app.M1SetTextArea_2 = uitextarea(app.TankBTab);
            app.M1SetTextArea_2.Editable = 'off';
            app.M1ActualTextArea_2Label = uilabel(app.TankBTab);
            app.M1ActualTextArea_2Label.Text = 'M1 Actual';
            app.M1ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M1ActualTextArea_2.Editable = 'off';
            app.M2SetTextArea_2Label = uilabel(app.TankBTab);
            app.M2SetTextArea_2Label.Text = 'M2 Set';
            app.M2SetTextArea_2 = uitextarea(app.TankBTab);
            app.M2SetTextArea_2.Editable = 'off';
            app.M2ActualTextArea_2Label = uilabel(app.TankBTab);
            app.M2ActualTextArea_2Label.Text = 'M2 Actual';
            app.M2ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M2ActualTextArea_2.Editable = 'off';
            app.M3SetTextArea_2Label = uilabel(app.TankBTab);
            app.M3SetTextArea_2Label.Text = 'M3 Set';
            app.M3SetTextArea_2 = uitextarea(app.TankBTab);
            app.M3SetTextArea_2.Editable = 'off';
            app.M3ActualTextArea_2Label = uilabel(app.TankBTab);
            app.M3ActualTextArea_2Label.Text = 'M3 Actual';
            app.M3ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M3ActualTextArea_2.Editable = 'off';
            app.WLSetTextArea_2Label = uilabel(app.TankBTab);
            app.WLSetTextArea_2Label.Text = 'WL Set';
            app.WLSetTextArea_2 = uitextarea(app.TankBTab);
            app.WLSetTextArea_2.Editable = 'off';
            app.WLActualTextArea_2Label = uilabel(app.TankBTab);
            app.WLActualTextArea_2Label.Text = 'WL Actual';
            app.WLActualTextArea_2 = uitextarea(app.TankBTab);
            app.WLActualTextArea_2.Editable = 'off';
            app.RunProgram2CheckBox = uicheckbox(app.TankBTab);
            app.RunProgram2CheckBox.ValueChangedFcn = createCallbackFcn(app, @RunProgram2CheckBoxValueChanged, true);
            app.RunProgram2CheckBox.Text = 'Run Program (Tank B)';
            app.ConnecttoArduino2Button = uibutton(app.TankBTab, 'state');
            app.ConnecttoArduino2Button.ValueChangedFcn = createCallbackFcn(app, @ConnecttoArduino2ButtonValueChanged, true);
            app.ConnecttoArduino2Button.Text = 'Connect to Arduino';
            app.M4ActualTextAreaLabel = uilabel(app.TankBTab);
            app.M4ActualTextAreaLabel.Text = 'M4 Actual';
            app.M4ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M4ActualTextArea_2.Editable = 'off';
            app.M4SetTextAreaLabel = uilabel(app.TankBTab);
            app.M4SetTextAreaLabel.Text = 'M4 Set';
            app.M4SetTextArea_2 = uitextarea(app.TankBTab);
            app.M4SetTextArea_2.Editable = 'off';
            app.M5ActualTextAreaLabel = uilabel(app.TankBTab);
            app.M5ActualTextAreaLabel.Text = 'M5 Actual';
            app.M5ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M5ActualTextArea_2.Editable = 'off';
            app.M5SetTextAreaLabel = uilabel(app.TankBTab);
            app.M5SetTextAreaLabel.Text = 'M5 Set';
            app.M5SetTextArea_2 = uitextarea(app.TankBTab);
            app.M5SetTextArea_2.Editable = 'off';
            app.M6ActualTextAreaLabel = uilabel(app.TankBTab);
            app.M6ActualTextAreaLabel.Text = 'M6 Actual';
            app.M6ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M6ActualTextArea_2.Editable = 'off';
            app.M6SetTextAreaLabel = uilabel(app.TankBTab);
            app.M6SetTextAreaLabel.Text = 'M6 Set';
            app.M6SetTextArea_2 = uitextarea(app.TankBTab);
            app.M6SetTextArea_2.Editable = 'off';
            app.M7ActualTextAreaLabel = uilabel(app.TankBTab);
            app.M7ActualTextAreaLabel.Text = 'M7 Actual';
            app.M7ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M7ActualTextArea_2.Editable = 'off';
            app.M7SetTextAreaLabel = uilabel(app.TankBTab);
            app.M7SetTextAreaLabel.Text = 'M7 Set';
            app.M7SetTextArea_2 = uitextarea(app.TankBTab);
            app.M7SetTextArea_2.Editable = 'off';
            app.M8ActualTextAreaLabel = uilabel(app.TankBTab);
            app.M8ActualTextAreaLabel.Text = 'M8 Actual';
            app.M8ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M8ActualTextArea_2.Editable = 'off';
            app.M8SetTextAreaLabel = uilabel(app.TankBTab);
            app.M8SetTextAreaLabel.Text = 'M8 Set';
            app.M8SetTextArea_2 = uitextarea(app.TankBTab);
            app.M8SetTextArea_2.Editable = 'off';
            app.M9ActualTextAreaLabel = uilabel(app.TankBTab);
            app.M9ActualTextAreaLabel.Text = 'M9 Actual';
            app.M9ActualTextArea_2 = uitextarea(app.TankBTab);
            app.M9ActualTextArea_2.Editable = 'off';
            app.M9SetTextAreaLabel = uilabel(app.TankBTab);
            app.M9SetTextAreaLabel.Text = 'M9 Set';
            app.M9SetTextArea_2 = uitextarea(app.TankBTab);
            app.M9SetTextArea_2.Editable = 'off';
            app.ComPortEditField_2Label = uilabel(app.TankBTab);
            app.ComPortEditField_2Label.Text = 'Com Port:';
            app.ComPortEditField_2 = uieditfield(app.TankBTab, 'text');
            app.ComPortEditField_2.ValueChangedFcn = createCallbackFcn(app, @ComPortEditField_2ValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = musselHeater_04jun2019

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end